classdef opts_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProjectSetupPanel             matlab.ui.container.Panel
        SelectFilesButton             matlab.ui.control.Button
        ChangeSaveFolderButton        matlab.ui.control.Button
        SavefilenameLabel             matlab.ui.control.Label
        SavefilenameEditField         matlab.ui.control.EditField
        DetectionsFile1Label          matlab.ui.control.Label
        DetectionsFile2Label          matlab.ui.control.Label
        DCalibrationFileLabel         matlab.ui.control.Label
        DTrackingOptionsPanel         matlab.ui.container.Panel
        FirstframeEditFieldLabel      matlab.ui.control.Label
        FirstframeEditField           matlab.ui.control.NumericEditField
        LastframeEditFieldLabel       matlab.ui.control.Label
        LastframeEditField            matlab.ui.control.NumericEditField
        MinTrackLengthEditFieldLabel  matlab.ui.control.Label
        MinTrackLengthEditField       matlab.ui.control.NumericEditField
        MaxgapLengthEditFieldLabel    matlab.ui.control.Label
        MaxgapLengthEditField         matlab.ui.control.NumericEditField
        MatchThresholdEditFieldLabel  matlab.ui.control.Label
        MatchThresholdEditField       matlab.ui.control.NumericEditField
        DLTThresholdEditFieldLabel    matlab.ui.control.Label
        DLTThresholdEditField         matlab.ui.control.NumericEditField
        XYdivisorEditFieldLabel       matlab.ui.control.Label
        XYdivisorEditField            matlab.ui.control.NumericEditField
        XYZdivisorEditField_2Label    matlab.ui.control.Label
        XYZdivisorEditField           matlab.ui.control.NumericEditField
        Make3DTracksButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        SavePathname % Path for saving data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.CallingApp = main_app;
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Choose first detections file');
            
            data1 = load([pathname filename]);
            app.SavePathname = pathname;
            
            % Load 2D opts
            app.CallingApp.opts = data1.opts;
            app.CallingApp.dets = data1.dets;
            
            % Display filename
            app.DetectionsFile1Label.Text = filename;
            
            % Set default Save filename
            app.SavefilenameEditField.Value = [filename(1:end-4) '_3D_data.mat'];
            
            %
            [filename2, pathname2] = uigetfile([pathname '*.mat'], 'Choose second detections file');
            
            data2 = load([pathname2 filename2]);
            
            % Check that opts are the same?
            
            % Save uvdata
            app.CallingApp.dets.uvData{2} = data2.dets.uvData{1};
            app.DetectionsFile2Label.Text = filename2;
            
            % Look up first and last frames of detection data
            first_frame = min([app.CallingApp.dets.uvData{1}(:,1); app.CallingApp.dets.uvData{2}(:,1)]);
            last_frame  = max([app.CallingApp.dets.uvData{1}(:,1); app.CallingApp.dets.uvData{2}(:,1)]);
            
            app.FirstframeEditField.Value = first_frame;
            app.LastframeEditField.Value = last_frame;
            
            % Load Calibration file
            [filename, pathname] = uigetfile([pathname '\*easyWandData.mat'], 'Choose easyWand Calibration File');
            %load([pathname filename]);
            app.DCalibrationFileLabel.Text = filename;
            
            % load DLT coefficients
            load([pathname filename]);
            app.CallingApp.dets.dltCoefs=easyWandData.coefs;
            
            % Save image sizes
            for i = 1:2
                app.CallingApp.opts.imageSize{i} = [easyWandData.imageWidth(i) easyWandData.imageHeight(i)];
            end            
 
            
            filename_base = filename(1:end-16);
            
            % Auto find Undistortion files
            distCoefsFile{1} = [filename_base 'cam1Tforms.mat'];
            distCoefsFile{2} = [filename_base 'cam2Tforms.mat'];
            
            app.CallingApp.dets.numCams = 2;
            
            % load distortion coefficients
            for i=1:app.CallingApp.dets.numCams
              if isempty(distCoefsFile{i})==false
                tforms = load([pathname distCoefsFile{i}]);
                app.CallingApp.dets.camd{i}= tforms.camd;
                app.CallingApp.dets.camud{i}=tforms.camud;
                clear tforms
              else
                app.CallingApp.dets.camd{i}=[];
                app.CallingApp.dets.camud{i}=[];
                msgbox('Tforms files not found proceeding without distortion/undistortion')
              end
            end
        end

        % Button pushed function: Make3DTracksButton
        function Make3DTracksButtonPushed(app, event)
            %Look up 3D tracking options
            opts = app.CallingApp.opts;
            opts.dltThreshold= app.DLTThresholdEditField.Value;
            opts.startFrame=app.FirstframeEditField.Value;
            opts.endFrame=app.LastframeEditField.Value;
            opts.minimumTrackLength = app.MinTrackLengthEditField.Value;
            opts.maximumGapLength   = app.MaxgapLengthEditField.Value;
            opts.xyzJoiningDivisor  = app.XYZdivisorEditField.Value; % larger --> less weight on 3D info for track forming
            opts.xyJoiningDivisor   = app.XYdivisorEditField.Value; % larger --> less weight on 2D info for track forming
            opts.badMatchThreshold  = app.MatchThresholdEditField.Value; % larger --> allow larger leaps in track joining
            
            if ~isfield(opts, 'offsets')
                opts.offsets = [0,0];
                opts.useMask = 0;
                opts.minNumCams = 2;
                opts.filterFrequency = 3;
                opts.frameRate = opts.FrameRate;
            end
            
            % Save opts
            app.CallingApp.opts = opts;
            
            % Process 3D Tracking
            app.CallingApp.dets = UVto3D_v4ajc(opts, app.CallingApp.dets, 1);
            
            % Save Results
            dets = app.CallingApp.dets;
            opts = app.CallingApp.opts;
            save([app.SavePathname app.SavefilenameEditField.Value],'dets','opts','-v7.3');
            msgbox('3D Data saved');
        end

        % Button pushed function: ChangeSaveFolderButton
        function ChangeSaveFolderButtonPushed(app, event)
            app.SavePathname = uigetdir(app.SavePathname,'Select the folder where you would like the data to be saved');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ProjectSetupPanel = uipanel(app.UIFigure);
            app.ProjectSetupPanel.Title = 'Project Setup';
            app.SelectFilesButton = uibutton(app.ProjectSetupPanel, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.ChangeSaveFolderButton = uibutton(app.ProjectSetupPanel, 'push');
            app.ChangeSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSaveFolderButtonPushed, true);
            app.ChangeSaveFolderButton.Text = 'Change Save Folder';
            app.SavefilenameLabel = uilabel(app.ProjectSetupPanel);
            app.SavefilenameLabel.Text = 'Save filename:';
            app.SavefilenameEditField = uieditfield(app.ProjectSetupPanel, 'text');
            app.SavefilenameEditField.Tooltip = {'filename where data will be saved.'};
            app.DetectionsFile1Label = uilabel(app.ProjectSetupPanel);
            app.DetectionsFile1Label.Text = 'Detections File 1';
            app.DetectionsFile2Label = uilabel(app.ProjectSetupPanel);
            app.DetectionsFile2Label.Text = 'Detections File 2';
            app.DCalibrationFileLabel = uilabel(app.ProjectSetupPanel);
            app.DCalibrationFileLabel.Text = '3D Calibration File';
            app.DTrackingOptionsPanel = uipanel(app.UIFigure);
            app.DTrackingOptionsPanel.Title = '3D Tracking Options';
            app.FirstframeEditFieldLabel = uilabel(app.DTrackingOptionsPanel);
            app.FirstframeEditFieldLabel.Text = 'First frame';
            app.FirstframeEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.FirstframeEditField.RoundFractionalValues = 'on';
            app.FirstframeEditField.ValueDisplayFormat = '%.0f';
            app.FirstframeEditField.Tooltip = {'First frame of the video that will be processed'};
            app.FirstframeEditField.Value = 1;
            app.LastframeEditFieldLabel = uilabel(app.DTrackingOptionsPanel);
            app.LastframeEditFieldLabel.Text = 'Last frame';
            app.LastframeEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.LastframeEditField.RoundFractionalValues = 'on';
            app.LastframeEditField.ValueDisplayFormat = '%.0f';
            app.LastframeEditField.Tooltip = {'Last frame of the video that will be processed'};
            app.LastframeEditField.Value = 100;
            app.MinTrackLengthEditFieldLabel = uilabel(app.DTrackingOptionsPanel);
            app.MinTrackLengthEditFieldLabel.Text = 'Min Track Length';
            app.MinTrackLengthEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.MinTrackLengthEditField.Tooltip = {'Minimum number of points required for a track (Tracks shorter than this length will be deleted)'};
            app.MinTrackLengthEditField.Value = 5;
            app.MaxgapLengthEditFieldLabel = uilabel(app.DTrackingOptionsPanel);
            app.MaxgapLengthEditFieldLabel.Text = 'Max gap Length';
            app.MaxgapLengthEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.MaxgapLengthEditField.Tooltip = {'Maximum number of frames between detections in a track'};
            app.MaxgapLengthEditField.Value = 10;
            app.MatchThresholdEditFieldLabel = uilabel(app.DTrackingOptionsPanel);
            app.MatchThresholdEditFieldLabel.Text = 'Match Threshold';
            app.MatchThresholdEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.MatchThresholdEditField.Tooltip = {'maximum acceptable number of pixels between the predicted location of the track and a detection to be accepted as part of the track. Use higher values when there are more variable frame rates or faster-moving objects.'};
            app.MatchThresholdEditField.Value = 20;
            app.DLTThresholdEditFieldLabel = uilabel(app.DTrackingOptionsPanel);
            app.DLTThresholdEditFieldLabel.Text = 'DLT Threshold';
            app.DLTThresholdEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.DLTThresholdEditField.Tooltip = {'maximum acceptable number of pixels between the predicted location of the track and a detection to be accepted as part of the track. Use higher values when there are more variable frame rates or faster-moving objects.'};
            app.DLTThresholdEditField.Value = 10;
            app.XYdivisorEditFieldLabel = uilabel(app.DTrackingOptionsPanel);
            app.XYdivisorEditFieldLabel.Text = 'XY divisor';
            app.XYdivisorEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.XYdivisorEditField.Value = 1;
            app.XYZdivisorEditField_2Label = uilabel(app.DTrackingOptionsPanel);
            app.XYZdivisorEditField_2Label.Text = 'XYZ divisor';
            app.XYZdivisorEditField = uieditfield(app.DTrackingOptionsPanel, 'numeric');
            app.XYZdivisorEditField.Value = 5;
            app.Make3DTracksButton = uibutton(app.UIFigure, 'push');
            app.Make3DTracksButton.ButtonPushedFcn = createCallbackFcn(app, @Make3DTracksButtonPushed, true);
            app.Make3DTracksButton.Text = 'Make 3D Tracks!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opts_3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end